classdef networkEdit_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        mainFig                     matlab.ui.Figure
        menuFile                    matlab.ui.container.Menu
        menuLoad                    matlab.ui.container.Menu
        menuClear                   matlab.ui.container.Menu
        menuSave                    matlab.ui.container.Menu
        menuQuit                    matlab.ui.container.Menu
        ResetGUIButton              matlab.ui.control.Button
        pushbuttonReplotNetwork     matlab.ui.control.Button
        textWillUpdate              matlab.ui.control.Label
        pushbuttonSetParentNode     matlab.ui.control.Button
        checkboxMergeAll            matlab.ui.control.CheckBox
        pushbuttonFilterActive      matlab.ui.control.Button
        signal                      matlab.ui.control.Label
        pushbuttonMerge             matlab.ui.control.Button
        pushbuttonImgReset          matlab.ui.control.Button
        sliderBrightness            matlab.ui.control.Slider
        text3                       matlab.ui.control.Label
        text2                       matlab.ui.control.Label
        sliderContrast              matlab.ui.control.Slider
        uipanelNodes                matlab.ui.container.Panel
        pushbuttonColorByDegree     matlab.ui.control.Button
        pushbuttonUnselectNode      matlab.ui.control.Button
        pushbuttonAddNode           matlab.ui.control.Button
        pushbuttonUnselectAllNodes  matlab.ui.control.Button
        pushbuttonSelArea           matlab.ui.control.Button
        pushbuttonSelectNode        matlab.ui.control.Button
        uipanelEdges                matlab.ui.container.Panel
        pushbuttonSplitEdge         matlab.ui.control.Button
        ShowLoops                   matlab.ui.control.Button
        uibuttongroupWidth          matlab.ui.container.ButtonGroup
        pushbuttonWidthCancel       matlab.ui.control.Button
        pushbuttonWidthCalc         matlab.ui.control.Button
        pushbuttonWidthMeas         matlab.ui.control.Button
        pushbuttonAddEdge           matlab.ui.control.Button
        pushbuttonUnselectAllEdges  matlab.ui.control.Button
        pushbuttonUnselectEdge      matlab.ui.control.Button
        pushbuttonSelectEdge        matlab.ui.control.Button
        pushbuttonRemoveSelected    matlab.ui.control.Button
    end

    properties (Access=public)
        fig % main figure for the app
    end

    methods (Access = private)
        function ActionsDisable(app, handles)                
                hb = findobj(app.mainFig, 'Type','uibutton');
                for i=1:length(hb)
                    hb(i).Enable = 'Off';
                end
                handles.sliderContrast.Enable = 'Off';
                handles.sliderBrightness.Enable = 'Off';

                % leave the reset gui button functional
                app.ResetGUIButton.Enable = 'on';
            return
        end
        
        function ActionsEnable(app, handles)
                hb = findobj(app.mainFig,'Type','uibutton');
                for i=1:length(hb)
                    hb(i).Enable = 'On';
                end
                handles.sliderContrast.Enable = 'On';
                handles.sliderBrightness.Enable = 'On';
            return
        end
        
        function EndAction(app, handles)
            global guilock
                ActionsEnable(app, handles);
                guilock = false;
                handles.signal.String = '';
                figure(app.fig)
                set(gcf,'Pointer','Arrow');
            return
        end
        
        function LoadData(app)
            % --------------------------------------------------------------------
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Manage menu
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
                global NTobj NTlocal imgObj plotoptObj selNodes selEdges fileName imageH
            %     global imgCData0
            
                close(figure(1))                
                NTobj = [];
                imgObj = [];
                plotoptObj = [];
                selNodes = [];
                selEdges = [];
            
                [file,path] = uigetfile('../*.mat');
                fileName = [path file];
            % fileName = '/home/matlab/Lena/networktools/exampleERnetwork.mat';
                load(fileName);
            
                NTobj = NT;
                imgObj = img;
                plotoptObj = plotopt;
                if isempty(NTobj.edgewidth)
                    NTobj.edgewidth = cell(NT.nedge,1);
                end
                NTlocal = getNTlocal(app, NTobj);
                            
                app.fig = figure(1);
                imageH = imshow(imgObj,[]);
            %     imgCData0 = imageH.CData;
            
                dispNetWithImage(app);
                figure(app.fig);
            
            return
        end
        
        function PickableOff(app, obj)
                global guilock 
                guilock = false;
                figure(app.fig)
                set(gcf,'Pointer','arrow');
                for i=1:length(obj)
                    if(~isempty(obj(i)))
                        obj(i).PickableParts = 'none';
                        obj(i).HitTest = 'off';
                    end
                end
            return
        end
        
        function PickableOn(app, obj)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %   UTILS
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
                global guilock 
                guilock = true;
                figure(app.fig)
                for i=1:length(obj)
                    if(~isempty(obj(i)))
                        obj(i).PickableParts = 'all';
                        obj(i).HitTest = 'on';
                    end
                end
            return
        end
        
        function StartAction(app, handles, str)
            global guilock
                ActionsDisable(app, handles);
                guilock = true;
                handles.signal.String = str;
                figure(app.fig)
            %     set(gcf,'Pointer','watch');
            return
        end
        
        function dispNetWithImage(app)
            global NTobj imgObj plotoptObj nodeplotH edgeplotH imageH selNodes;
            
                try
                    delete(nodeplotH)
                    for lc = 1:length(edgeplotH)
                        delete(edgeplotH(lc))
                    end
                end
            
                app.fig=figure(1);
            
                if (exist('imageH','var'))
                    if isempty(imageH)
                        imageexists = false;
                    else
                        imageexists = isgraphics(imageH);
                        if (imageexists)
                            imageexists = isvalid(imageH);
                        end
                    end
                else
                    imageexists = false;
                end
            
                if (~imageexists) % redraw figure
                    if (~isempty(imgObj))
                        % rescale image to be between 0 1
                        imgObj = double(imgObj)/max(double(imgObj(:)));
                        imageH = imshow(imgObj,[0 1]);
                    end
                    set(app.fig, 'Position', [40 40 500 500]);
                    set(gca,'Position',[0,0,1,1])
                end
                hold all
            
                % plot network
                app.fig=figure(1);
                hold on
                plotoptObj.datatipindex = true;
                [nodeplotH,edgeplotH] = NTobj.plotNetwork(plotoptObj);
                hold off
            
                if ~isempty(selNodes)
                    figure(app.fig);
                    hold on
                    selscatH = scatter(NTobj.nodepos(selNodes,1), NTobj.nodepos(selNodes,2),...
                        20, 'b', 'filled');
                    selscatH.PickableParts='none';
                    hold off
                end
            
                nodeplotH.PickableParts = 'none';
                for lc = 1:length(edgeplotH);
                    edgeplotH(lc).PickableParts = 'none';
                end
            
                if (~imageexists)
                    set(gca,'Position',[0,0,1,1])
                end
            return
        end
        
        function lineInd = edge2lineInd(app, edge, Lines, edgepath)
                lineInd = 0;
                for k=1:length(Lines)
                    ex = edgepath{edge}(:,1);
                    lx = Lines(k).XData(:);
                    if length(lx) ~= length(ex)
                        continue;
                    end
                    ey = edgepath{edge}(:,2);
                    ly = Lines(k).YData(:);
                    if nnz(ex-lx)==0 && nnz(ey-ly)==0
                        lineInd = k;
                        break;
                    end
                end
            
                if lineInd==0 || Lines(lineInd).edgeind ~= edge
                    aaa=0;
                end
                Lines(lineInd).edgeind = edge;
            return
        end
        
        function mapall2act = filterActiveNetwork(app, nodepos, edgenodes, nodeact, edgeact, edgepath, edgewidth, NT)
                % from a full list of nodes and edges (some inactive)
                % filter out only the active ones and make a clean network obj
            
                %NT = NetworkObj();
            
                prevrootnode = NT.rootnode;
            
                % copy over active node positions
                NT.nodepos = nodepos(nodeact,:);
            
            
                % copy over active edges
                newedgenodes = edgenodes(edgeact,:);
                NT.edgenodes = zeros(nnz(edgeact),2);
            
                % Clean up which nodes the edges are pointing to:
            
                % this maps the list of all nodes to their index in the list of active nodes
                mapall2act = zeros(1,size(nodepos,1));
                mapall2act(nodeact) = 1:nnz(nodeact);
            
                NT.edgenodes(:,1) = mapall2act(newedgenodes(:,1));
                NT.edgenodes(:,2) = mapall2act(newedgenodes(:,2));
            
                % copy over active edge paths
                NT.edgepath = edgepath(edgeact);
            
                % copy over edge widths
                NT.edgewidth = edgewidth(edgeact);
            
                % set up all the other arrays in the network
                NT.setupNetwork();
            
                % deal with cumulative and total edge lengths
                NT.setCumEdgeLen(1:NT.nedge, true);
            
                % update root node
                if (~isnan(prevrootnode))
                    NT.rootnode = mapall2act(prevrootnode);
            
                    % update directionality info
                    isset = false(NT.nedge,1);
                    wasreversed = false(NT.nedge,1);
                    [isset,wasreversed] = directedTreeEdges(NT,NT.rootnode,isset,wasreversed);
                end
            
            return
        end
        
        function [iSel,xy_closest,frac_closest,dMin] = findNearestEdge(app, xy)
            % Find the point along all edge paths closest to the query point xy
            % iSel = index of closest edge
            % xy_closest = coordinates of closest point along all paths
            % (could be in between path-defining points)
            % frac_closest = fraction along the path corresponding to
            % closest point

                global NTobj NTlocal
            
                dMin = inf;
                for ec = 1:NTobj.nedge
                    if (~NTlocal.edgeactive)
                        continue
                    end
                    curvexy = NTobj.edgepath{ec};
                    [xyc,distance,t_a] = distance2curve(curvexy,xy,'linear');
                    if distance < dMin
                        xy_closest = xyc;
                        frac_closest = t_a;
                        iSel = ec;
                        dMin = distance;
                    end
                end

                % dMin = inf;
                % iSel =-1;
                % for i=1:NTobj.nedge
                %     if ~NTlocal.edgeactive
                %         continue;
                %     end
                %     d = NTobj.edgepath{i} - xy;
                %     d2 = d(:,1).^2 + d(:,2).^2;
                %     [m,ptind] = min(d2);
                %     if m<dMin
                %         iSel = i;
                %         pSel = ptind;
                %         dMin = m;
                %     end
                % end
            return
        end
        
        function ind = findNearestNode(app, nodepos, xy)
                global NTlocal
                dNodes = nodepos - xy;
                d2 = dNodes(:,1).^2 + dNodes(:,2).^2;
                M = min(d2(NTlocal.nodeactive));
                ind = find(d2 == M);
            return
        end
        
        function degrees = getDegrees(app, edgenodes, nnodes, edgeactive, selNodes)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Actions
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
                degrees = zeros(nnodes,1);
                    E1 = edgenodes(:,1);
                    E2 = edgenodes(:,2);
                for in=1:length(selNodes)
                    node = selNodes(in);
                    for ie=1:length(edgenodes)
                        if (E1(ie)==node || E2(ie)==node) && edgeactive(ie)
                            degrees(node) = degrees(node) + 1;
                        end
                    end
                end
            return
        end
        
        function NTlocal = getNTlocal(app, NTobj)
                NTlocal.nodepos = NTobj.nodepos;
                NTlocal.edgenodes = NTobj.edgenodes;
                NTlocal.edgepath = NTobj.edgepath;
                NTlocal.nodeactive = true(NTobj.nnode,1);
                NTlocal.edgeactive = true(NTobj.nedge,1);
                NTlocal.edgewidth=NTobj.edgewidth;
                NTlocal.filtered = true;
            return
        end
        
        function [nodeedges, nodenodes] = getNodeNeighbors(app, edgenodes, nnodes, nodeactive, edgeactive, selNodes)
                nedges = size(edgenodes,1);
                nodeedges = zeros(nnodes,4);
                nodenodes = zeros(nnodes,4);
                ke = zeros(nnodes,1);
                kn = zeros(nnodes,1);
                for in=1:length(selNodes)
                    node = selNodes(in);
                    E1 = edgenodes(:,1);
                    E2 = edgenodes(:,2);
                    for ie = 1:nedges
                        if any(edgenodes(ie,:) == node) && edgeactive(ie)
                            ke(in) = ke(in) +1;
                            nodeedges(node, ke(in)) = ie;
                        end
            
                        if E1(ie)==node && edgeactive(ie)
                            kn(in) = kn(in) + 1;
                            nodenodes(node, kn(in)) = E2(ie);
                        end
                        if E2(ie)==node && edgeactive(ie)
                            kn(in) = kn(in) + 1;
                            nodenodes(node, kn(in)) = E1(ie);
                        end
                    end
                end
            return
        end
        
        function [w, d, iSel] = getWidth(app, ep)
                global NTobj
            
                w = 0;
                d = 0;
                iSel = -1;
            
                X1 = ep(1,1); Y1 = ep(1,2); X2 = ep(2,1); Y2 = ep(2,2);
                xy = [0.5*(X1+X2) 0.5*(Y1+Y2)];
                % find nearest edge to center point of drawn segment
                iSel = findNearestEdge(app, xy);
                edge = NTobj.edgepath{iSel}; % path of the edge
                cum = NTobj.cumedgelen{iSel};
            
                % find intersection between drawn segment and edge
                seg1 = ep;
                segdiff = seg1(2,:)-seg1(1,:);
                w = norm(segdiff);
            
                for j = 1:size(edge,1)-1
                    seg2 = edge(j:j+1,:);
                    [t1,t2,intpt,doint] = segsegintersect(seg1', seg2');
            
                    if doint
                        xCros = intpt(1); yCros = intpt(2);
                        d = cum(j)+norm(intpt'-edge(j,:));
                        break
                    end
                end
            return
        end
        
        function chain = makeNodeChains(app, selN, nodenodes)
            % Organize all selected nodes into chains of sequential nodes
                j = 1;
                while true
                    n0 = selN(1);
                    chain{j}.nodes = [n0];
                    selN(selN==n0) = [];
                    n = n0;
                    while true
                        n1 = nodenodes(n,1);
                        if any(selN(:) == n1)
                            chain{j}.nodes = [n1 chain{j}.nodes];
                            selN(selN==n1) = [];
                            n = n1;
                        else
                            break;
                        end
                    end
            
                    n = n0;
                    while true
                        n2 = nodenodes(n,2);
                        if any(selN(:) == n2)
                            chain{j}.nodes = [chain{j}.nodes n2];
                            selN(selN==n2) = [];
                            n = n2;
                        else
                            break;
                        end
                    end
            
                    if isempty(selN)
                        break;
                    end
                    j = j+1;
                end
            return
        end
        
        function removeSelected(app)
                global NTlocal selNodes selEdges
            
                if isempty(selNodes) & isempty(selEdges)
                    return
                end
            
                figure(app.fig);
                % Inactivate selected nodes
                scatter = findobj(gca,'Type','scatter');
                scatter.SizeData(selNodes) = 0.001;
                NTlocal.nodeactive(selNodes) = false;
            
                % Add adjacent edges to selected for later deletion
                for j=1:length(selNodes)
                    k = selNodes(j);
                    scatter.CData(k,:) = [0 0 0];
                    for ec=1:length(NTlocal.edgepath)
                        if any(NTlocal.edgenodes(ec,:) == k)
                            selEdges = [selEdges ec];
                        end
                    end
                end
                selEdges = sort(unique(selEdges));
            
                % Inactivate selected edges
                Lines = findobj(gca,'Type','line');
                ind = [];
                for j=1:length(Lines)
                    if any(selEdges(:) == Lines(j).edgeind)
                        Lines(j).Visible = 'off';
                    end
                end
                NTlocal.edgeactive(selEdges) = false;
            
                selNodes = [];
                selEdges = [];
                NTlocal.filtered = false;
            return
        end
        
        function Nodes = reorderNodes(app, selN, nodenodes, edgenodes)
                I = 0;
                for i=1:length(selN)
                    nn = nodenodes(selN(i),1:2);
                    if ~any(selN(:) == nn(1))
                        Nodes = [nn(1) selN(i)];
                        lastNode = nn(2);
                        I = i;
                        break;
                    end
                    if ~any(selN(:) == nn(2))
                        Nodes = [nn(2) selN(i)];
                        lastNode = nn(1);
                        I = i;
                        break;
                    end
                end
                selN(I) = [];
            
                % Organize Nodes sequentially
                if ~isempty(selN)
                    while true
                        for i=1:length(selN)
                            nn = nodenodes(selN(i),1:2);
                            if nn(1) == Nodes(end)
                                Nodes = [Nodes selN(i)];
                                lastNode = nn(2);
                                I = i;
                                break;
                            end
                            if nn(2) == Nodes(end)
                                Nodes = [Nodes selN(i)];
                                lastNode = nn(1);
                                I = i;
                                break;
                            end
                        end
                        selN(I) = [];
            
                        if isempty(selN)
                            break;
                        end
                    end
                end
                Nodes = [Nodes lastNode];
            return
        end
        
        function iSel = selectEdge(app, color)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Manage edges
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
                global edgeplotH guilock NTlocal
            
                figure(app.fig)
                L = findobj(gca,'Type','line');
                PickableOn(app, L);
                PickableOn(app, edgeplotH);
                try
                    figure(app.fig)
            
                    iSel=[];
                    w = 0;
                    display('Select desired edges. Then press any keyboard key (while the figure window is active)')
            
                    while ~w
                        w = waitforbuttonpress;
                    end
            
                    figure(app.fig)
                    datatips = findobj(gca,'Type','datatip');
            
                    % Cancel selection if 'Esc' hit
                    value = double(get(gcf,'CurrentCharacter'));
                    if value==27
                        delete(datatips)
                        PickableOff(app, L);
                        PickableOff(app, edgeplotH);
                        return
                    end
            
                    if ~isempty(datatips)
                        for idt=1:length(datatips)
                            Ln = datatips(idt).Parent;
                            Ln.Color = color;
                            iSel = [iSel Ln.edgeind];
                        end
            
                        delete(datatips)
                        %selEdges = [selEdges iSel];
                    end
                catch exception
                    guilock = false;
                    disp(getReport(exception))
                end
                figure(app.fig)
                L = findobj(gca,'Type','line');
                PickableOff(app, L);
                PickableOff(app, edgeplotH);
            
            %     Lines = findobj(gca,'Type','line');
            return
        end
        
        function ind = selectNode(app, addSelected, color)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Manage nodes
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
                global nodeplotH selNodes guilock                           
                %disp('Use datatips to select desired nodes. Then hit any keyboard key (while the figure window is active).')
            
               % try
                    figure(app.fig)
            
                    scatter = nodeplotH;
                    PickableOn(app, scatter);
                   % PickableOn(app, nodeplotH);
            
                    ind = [];
                    w = 0;
                    if ~ishandle(app.fig)
                        return;
                    end
                    figure(app.fig);
                    while ~w
                        w = waitforbuttonpress;
                    end
                    figure(app.fig)
                    datatips = findobj(gca,'Type','datatip');
            
                    % Cancel selection if 'Esc' hit
                    value = double(get(gcf,'CurrentCharacter'));
                    if value==27
                        figure(app.fig)
                        delete(datatips)
                        scatter = findobj(gca,'Type','scatter');
                        PickableOff(app, nodeplotH);
                        PickableOff(app, scatter);
                        return
                    end
            
                    if ~isempty(datatips)
                        ind = [datatips.DataIndex];
                        scatter = findobj(gca,'Type','scatter');
                        for i=1:length(ind)
                            scatter.CData(ind(i),:) = color;
                        end
            
                        if addSelected
                            selNodes = [selNodes ind];
                            selNodes = unique(selNodes);
                        end
            
                        delete(datatips)
                    end
                % catch exception
                %     guilock = false;
                %     disp(getReport(exception))
                % end
                if ~ishandle(app.fig)
                    return;
                end
                figure(app.fig)
                scatter = findobj(gca,'Type','scatter');
                PickableOff(app, scatter);
               % PickableOff(app, nodeplotH);
            return
        end
        
        function [Edges, pth] = traceEdges(app, Nodes, edges, edgenodes, edgepath)
            % Organize edges sequentially
                nN = length(Nodes);
                nE = length(edges);
                Edges = [];
                for i=1:nN-1
                    n1 = Nodes(i);
                    n2 = Nodes(i+1);
                    for j=1:nE
                        e = edgenodes(edges(j),:);
                        if e(1) == n1 && e(2) == n2 || e(1) == n2 && e(2) == n1
                            Edges = [Edges edges(j)];
                            if edgenodes(edges(j),:) == [n1 n2]
                                pth{i} = edgepath{edges(j)};
                            else
                                pth{i} = flipud(edgepath{edges(j)});
                            end
                            break;
                        end
                    end
                end
            return
        end
        
        
        function splitEdge(app,ec,frac)
            % split an edge in the local network structure
            % ec = edge index to split
            % frac = fraction along the edge to split at
            % new edges are ec and last one of the network
            % new node is the last one of the network            
            global NTlocal

            % get cumulative path lengths
            path = NTlocal.edgepath{ec};
            pathdiffs = path(2:end,:) - path(1:end-1,:);
            pathlens = sqrt(sum(pathdiffs.^2,2));            
            cumedgelen = [0,cumsum(pathlens')];

            % get splitting position
            splitlen = frac*cumedgelen(end); % how far along to split
            newpos = interp1(cumedgelen,path,splitlen);
            
            % index right below desired length
            ind = find(cumedgelen<splitlen,1,'last'); 

            nnode = size(NTlocal.nodepos,1);
            nedge = size(NTlocal.edgenodes,1);

            % add new node
            NTlocal.nodepos(nnode+1,:) = newpos;        
            % add a new edge
            NTlocal.edgenodes(end+1,:) = [NTlocal.edgenodes(ec,2),nnode+1];
            NTlocal.edgewidth{end+1} = [];
            % replace previous edge with one that goes to new node
            NTlocal.edgenodes(ec,2) = nnode+1;

            % make node and edges active
            NTlocal.nodeactive(nnode+1) = true;
            NTlocal.edgeactive(ec) = true;
            NTlocal.edgeactive(nedge+1) = true;
            NTlocal.filtered = 1;

            % update the edgepath
            NTlocal.edgepath{nedge+1} = flip([newpos; path(ind+1:end,:)]);
            
            if (norm(path(ind,:)-newpos)<2*eps)
                % in case newpos = a point along edgepath
                NTlocal.edgepath{ec}= path(1:ind,:);
            else
                NTlocal.edgepath{ec}= [path(1:ind,:); newpos];
            end

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function networkEdit_OpeningFcn(app, varargin)
            % --- Executes just before networkEdit is made visible.
            
            % Ensure that the app appears on screen when run
            movegui(app.mainFig, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to networkEdit (see VARARGIN)
                global NTobj NTlocal imgObj plotoptObj selNodes selEdges guilock
                % Choose default command line output for networkEdit
                handles.output = hObject;
            
                % Update handles structure
                guidata(hObject, handles);
            
                % specific for AF
            %     addpath /home/matlab/Lena/networktools;
            %     addpath /home/matlab/Lena/networktools/gui;
            
            %% set defaults
                NTobj = [];
                imgObj = [];
                plotoptObj = struct();
            
                for index = 1:2:length(varargin)
                    switch(lower(varargin{index}))
                        case 'nt'
                            NTobj = varargin{index+1};
                        case 'img'
                            imgObj = varargin{index+1};
                        case('plotopt')
                            plotoptObj = varargin{index+1};
                    end
                end
                if ~isempty(NTobj)
                    NTlocal = getNTlocal(app, NTobj);
                end
            
                selNodes = [];
                selEdges = [];
                if (~isempty(NTobj))
                    if (isempty(NTobj.edgewidth))
                        NTobj.edgewidth = cell(NTobj.nedge,1);
                    end
                    % reset edgevals
                    NTobj.edgevals={};
                    for ec = 1:NTobj.nedge
                        NTobj.edgevals{ec} = [];
                    end
                    dispNetWithImage(app);
                end
                            
                app.sliderContrast.Limits = [0,4];                
                app.sliderBrightness.Limits = [-0.5,0.5];                
                guilock = false;
            
                if (isempty(NTobj))
                    ActionsDisable(app, handles);
                else
                    ActionsEnable(app, handles);
                end
            
                set(handles.pushbuttonWidthCalc,'Enable','off');
                set(handles.pushbuttonWidthCancel,'Enable','off');
                % UIWAIT makes networkEdit wait for user response (see UIRESUME)
            % uiwait(handles.mainFig);
            
            return
        end

        % Button pushed function: ShowLoops
        function ShowLoops_Callback(app, event)
            % --- Executes on button press in ShowLoops.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to ShowLoops (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global NTlocal NTobj
            
            % make full network object
            NTcur = copy(NTobj);
            filterActiveNetwork(app, NTlocal.nodepos, NTlocal.edgenodes,...
                NTlocal.nodeactive, NTlocal.edgeactive, NTlocal.edgepath,NTlocal.edgewidth,...
                NTcur);
            
            % set up a connectivity matrix
            nnode = size(NTlocal.nodepos,1);
            nedge = size(NTlocal.edgenodes,1);
            A = zeros(nnode,nnode);
            for ec = 1:nedge
                if (NTlocal.edgeactive(ec,:))
                    n1 = NTlocal.edgenodes(ec,1); n2 = NTlocal.edgenodes(ec,2);
                    A(n1,n2) = 1;
                    A(n2,n1) = 1;
                end
            end
            % make into a graph structure
            NTgraph = graph(A);
            
            cycles = cyclebasis(NTgraph);
            cycleNodes = horzcat(cycles{1:end});
            
            figure(app.fig)
            scatter = findobj(gca,'Type','scatter');
            
            for i=cycleNodes
                scatter.CData(i,:) = [0,0,1];
            end
            
            return
        end

        % Menu selected function: menuClear
        function menuClear_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                global NTobj NTlocal selNodes
            
                close(figure(1))
                app.fig = NaN;
                NTobj = [];
                NTlocal = [];
                selNodes = [];
                            
                app.sliderContrast.Value = 1;                
                app.SliderBrightness.Value = 0;
            
                ActionsDisable(app, handles)
            return
        end

        % Menu selected function: menuLoad
        function menuLoad_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                LoadData(app);
                ActionsEnable(app, handles);
            return
        end

        % Menu selected function: menuQuit
        function menuQuit_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                close all
                clear all
            return
            step
        end

        % Menu selected function: menuSave
        function menuSave_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                global NTobj NTlocal imgObj plotoptObj fileName
            
                if ~NTlocal.filtered
                    filterActiveNetwork(app, NTlocal.nodepos, NTlocal.edgenodes,...
                    NTlocal.nodeactive, NTlocal.edgeactive, NTlocal.edgepath,NTlocal.edgewidth,NTobj);
                    NTlocal.filtered = true;
                end
            
                NT = NTobj;
                img = imgObj;
                plotopt = plotoptObj;
                newFile = [fileName(1:end-4) '_modified.mat'];
                uisave({'NT','img','plotopt'}, newFile);
            return
        end

        % Button pushed function: pushbuttonAddEdge
        function pushbuttonAddEdge_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                global NTlocal edgeplotH guilock
            
                if (guilock)
                    disp('Cannot draw new edge, gui is locked. Finish previous operation')
                    return
                end
                guilock = true;
            
                try
                    StartAction(app,handles,'Draw new edge path. When done, do right mouse click. Then adjust path and hit any key to continue')
                    figure(app.fig);
            
                    roi = drawpolyline;
            
                    w = 0;
                    %display('Draw new edge path. When done, do right mouse click. Then adjust path and hit any key to continue')
                    while ~w
                        w = waitforbuttonpress;
                    end
                    figure(app.fig);
                    pnt = roi.Position;
                    delete(roi);
            
                    ind1 = findNearestNode(app, NTlocal.nodepos, pnt(1,:));
                    pnt(1,:) = NTlocal.nodepos(ind1,:);
                    ind2 = findNearestNode(app, NTlocal.nodepos, pnt(end,:));
                    pnt(end,:) = NTlocal.nodepos(ind2,:);
                    x = pnt(:,1); y = pnt(:,2);
            
                    i = size(NTlocal.edgenodes,1) + 1;
            
                    NTlocal.edgenodes(i,:) = [ind1 ind2];
                    NTlocal.edgepath{i} = [x y];
                    NTlocal.edgewidth{i} = [];
                    NTlocal.edgeactive(i) = true;
            
                    hold on
                    edgeplotH(i) = plot(x,y, 'Color','g', 'LineWidth',2);
                    hold off
                    edgeplotH(i).addprop('edgeind');
                    edgeplotH(i).edgeind = i;
                    EndAction(app,handles)
                catch exception
                    guilock = false;
                    disp(getReport(exception))
                end
                NTlocal.filtered = false;
                guilock = false;
            return
        end

        % Button pushed function: pushbuttonAddNode
        function pushbuttonAddNode_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                global NTlocal guilock nodeplotH
            
                StartAction(app,handles,['Click on positions of new nodes. When done, right-click with mouse.'])
                
                figure(app.fig);
                          
                rois = images.roi.Point.empty; % Initialize array of point ROIs
                
                % 1. Point Placement Phase
                setappdata(app.fig, 'stopDrawing', false);
                %kl = listener(app.fig, 'WindowKeyPress', @(~,~) setappdata(app.fig, 'stopDrawing', true));
                % Listener for mouse presses (detects Right Click / Button 3)
                ml = listener(app.fig, 'WindowMousePress', @(src, evt) ...
                setappdata(src, 'stopDrawing', strcmp(src.SelectionType, 'alt')));

                while ~getappdata(app.fig, 'stopDrawing')
                    try
                        h = drawpoint; % Click to place an individual point
                        
                        % If user pressed a key during placement or canceled
                        if getappdata(app.fig, 'stopDrawing') || isempty(h.Position)
                            if isvalid(h), delete(h); end
                            break;
                        end
                        
                        rois = [rois, h]; %#ok<AGROW>
                    catch
                        break;
                    end
                end
                if isvalid(ml); delete(ml); end
                
                % 2. Point Adjustment Phase (matches your waitforbuttonpress logic)
                % At this point, all points are visible without connecting lines.
                % You can click and drag any point to adjust it. Press any key to finish.
                app.signal.Text = 'Adjust newly drawn nodes. When done, hit any key to continue. New nodes will initially be detached from rest of network.';
                w = 0;
                while ~w
                    w = waitforbuttonpress;
                end
                
                % 3. Extract positions into an N-by-2 matrix (identical structure to roi.Position)
                figure(app.fig);
                validRois = rois(isvalid(rois));
                if ~isempty(validRois)
                    pt = vertcat(validRois.Position);
                else
                    pt = [];
                end
                
                % 4. Clean up point objects
                delete(validRois);

                
                % delete existing scatter plot of nodes
                nodesize = nodeplotH.SizeData(1);
                dt = nodeplotH.DataTipTemplate;
                delete(nodeplotH) 

                % add more nodes to plot
                nnew = size(pt,1);
                NTlocal.nodepos(end+1:end+nnew,:) = pt;
                NTlocal.nodeactive(end+1:end+nnew) = true;                
                
                % replot all nodes, including new ones
                nodepos = NTlocal.nodepos;
                nnode = size(nodepos,1);

                hold all
                figure(app.fig);
                nodeplotH = scatter(nodepos(:,1),nodepos(:,2),nodesize*ones(nnode,1),repmat([1 0 0],nnode,1),'filled');
                hold off

                dt = nodeplotH.DataTipTemplate;
                dt.DataTipRows(1).Value = 1:nnode;
                dt.DataTipRows(1).Label = '';
                dt.DataTipRows(2:end) = [];
                dt.FontSize=6;
             
                NTlocal.filtered = false;
                PickableOff(app, nodeplotH);
                EndAction(app, handles)
            return
        end

        % Button pushed function: pushbuttonColorByDegree
        function pushbuttonColorByDegree_Callback(app, event)
            % --- Executes on button press in pushbuttonColorByDegree.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbuttonColorByDegree (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global NTlocal NTobj
            
            nedge = size(NTlocal.edgenodes,1);
            nnode = size(NTlocal.nodepos,1);
            degs = zeros(nnode,1);
            for ec = 1:nedge
                if (NTlocal.edgeactive(ec))
                    n1 = NTlocal.edgenodes(ec,1);
                    n2 = NTlocal.edgenodes(ec,2);
                    if (NTlocal.nodeactive(n1)); degs(n1) = degs(n1)+1; end
                    if (NTlocal.nodeactive(n2)); degs(n2) = degs(n2)+1; end
                end
            end
            
            colors = lines(max(degs));
            colors(1,:) = colors(1,:)*1.5;
            
            figure(app.fig)
            scatter = findobj(gca,'Type','scatter');
            
            for i=1:nnode
                if (degs(i)>0)
                    scatter.CData(i,:) = colors(degs(i),:);
                end
            end
            
            return
        end

        % Button pushed function: pushbuttonFilterActive
        function pushbuttonFilterActive_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                global NTobj NTlocal edgeplotH guilock
            
                % convert the current active network in the GUI into the workspace
                % object
            
                %if ~(NTlocal.filtered)
            
                 filterActiveNetwork(app, NTlocal.nodepos, NTlocal.edgenodes,...
                        NTlocal.nodeactive, NTlocal.edgeactive, NTlocal.edgepath,...
                        NTlocal.edgewidth,NTobj);
            
                 % also replot the network in the object
                 NTlocal = getNTlocal(app, NTobj);
                %end
                dispNetWithImage(app)
            return
        end

        % Button pushed function: pushbuttonImgReset
        function pushbuttonImgReset_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                global imageH imgObj
            
                imageH.CData = imgObj;                
                app.sliderContrast.Value = 1;                
                app.sliderBrightness.Value = 0;
            return
        end

        % Button pushed function: pushbuttonMerge
        function pushbuttonMerge_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                global NTlocal selNodes selEdges guilock
            
                % merging all degree 2 nodes?
                mergeall = handles.checkboxMergeAll.Value;
            
                if isempty(selNodes)& ~mergeall
                        return
                end
            
                if ~NTlocal.filtered
            %         % memorize selected nodes coordinates
            %         memNodes = NTlocal.nodepos(selNodes,:);
            %
            %         filterActiveNetwork(newf, ...
            %             NTlocal.nodepos, NTlocal.edgenodes,...
            %             NTlocal.nodeactive, NTlocal.edgeactive, ...
            %             NTlocal.edgepath, NTobj);
            %
            %         NTlocal = getNTlocal(NTobj);
            %         NTlocal.filtered = true;
            %
            %         % Update selNodes
            %         selN = [];
            %         for in=1:size(NTlocal.nodepos,1)
            %             if any(memNodes==NTlocal.nodepos(in,:))
            %                 selN = [selN in];
            %             end
            %         end
            %         selNodes = selN;
                end
            
                if (guilock)
                    disp('Cannot merge, gui is locked. Finish previous operation.')
                    return
                end
            
                if (mergeall)
                    %disp('Merging all edges is currently buggy. Do not use')
                    %return
                    % select all degree 2 nodes
                    nnode = size(NTlocal.nodepos,1);
                    degrees = getDegrees(app, NTlocal.edgenodes, ...
                        length(NTlocal.nodeactive), NTlocal.edgeactive, 1:nnode);
                    isSel = false(1,nnode);
                    isSel(selNodes) = true;
                    isSel(degrees==2) = true;
                    selNodes = find(isSel);
                end
            
                figure(app.fig)
                StartAction(app, handles, 'Merging edges...');
                try
                    degrees = getDegrees(app, NTlocal.edgenodes, ...
                        length(NTlocal.nodeactive), NTlocal.edgeactive, selNodes);
            
                    Lines = findobj(gca,'Type','line');
            
                    % Unselect all edges
                    for i=1:length(Lines)
                        Lines(i).Color = 'g';
                    end
                    selEdges = [];
            
                    nSel = length(selNodes);
            
                    % Restrict merging only for the nodes with 2 adjoined edges,
                    % unselect other nodes
                    scatter = findobj(gca,'Type','scatter');
                    iIgnore = [];
                    for i=1:nSel
                        if degrees(selNodes(i)) ~= 2
                            iIgnore = [iIgnore i];
                            scatter.CData(selNodes(i),:) = [1 0 0];
                        end
                    end
                    selNodes(iIgnore) = [];
                    nSel = length(selNodes);
            
                    % Edges involved
                    nnodes = length(NTlocal.nodepos);
                    [nodeedges, nodenodes] = getNodeNeighbors(app, NTlocal.edgenodes, ...
                        nnodes, NTlocal.nodeactive, NTlocal.edgeactive, selNodes);
            
                    edges = [];
                    for i=1:nSel
                        edges = [edges nodeedges(selNodes(i),1:2)];
                    end
                    edges = unique(edges);
                    nE = length(edges);
            
                    % Organize all selected nodes into chains of sequential nodes
                    chain = makeNodeChains(app, selNodes, nodenodes);
            
                    % Convert each chain into a single edge
                    scatter = findobj(gca,'Type','scatter');
                    Lines = findobj(gca,'Type','line');
                    for iChain=1:length(chain)
                        selN = chain{iChain}.nodes;
            
                        Nodes = reorderNodes(app, selN, nodenodes);
                        [Edges pth] = ...
                            traceEdges(app, Nodes, edges, NTlocal.edgenodes,...
                            NTlocal.edgepath);
                        NTlocal.edgenodes(Edges(1),:) = ...
                            [Nodes(1) Nodes(end)];
            
                        newpath = pth{1};
                        for i=2:length(pth)
                            newpath = [newpath(1:end-1,:); pth{i}];
                        end
            
                        for i=1:length(selN)
                            scatter.SizeData(selN(i)) = 0.001;
                            scatter.CData(selN(i),:) = [0 0 0];
                            edge1 = nodeedges(selN(i),1);
                            edge2 = nodeedges(selN(i),2);
            
                            lineInd1 = edge2lineInd(app, edge1, Lines, NTlocal.edgepath);
                            lineInd2 = edge2lineInd(app, edge2, Lines, NTlocal.edgepath);
                            if i>1
                                Lines(lineInd1).Visible = 'off';
                            end
                            if i==1
                                Lines(lineInd1).XData = newpath(:,1);
                                try
                                    Lines(lineInd1).YData = newpath(:,2);
                                catch
                                    aaa=0;
                                end
                            end
                            Lines(lineInd2).Visible = 'off';
            
                            if i>1
                                NTlocal.edgeactive(edge1) = false;
                            end
                            NTlocal.edgeactive(edge2) = false;
                        end
                        NTlocal.nodeactive(selN) = false;
            
                        NTlocal.edgepath{Edges(1)} = newpath;
                    end
            
                    % clear selected nodes
                    selNodes = [];
                    % local network topology has changed
                    NTlocal.filtered = 0;
            
                catch exception
                    guilock = false;
                    disp(getReport(exception))
                end
                EndAction(app, handles)
            return
        end

        % Button pushed function: pushbuttonRemoveSelected
        function pushbuttonRemoveSelected_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                StartAction(app, handles, 'Removing selected...')
                removeSelected(app);
                EndAction(app, handles)
            return
        end

        % Button pushed function: pushbuttonReplotNetwork
        function pushbuttonReplotNetwork_Callback(app, event)
            % --- Executes on button press in pushbuttonReplotNetwork.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbuttonReplotNetwork (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global NTobj NTlocal
            
            % replot the network in the GUI based on the current workspace object (in
            % NTobj)
            
            % also replot the network in the object
            NTlocal = getNTlocal(app, NTobj);
            %end
            dispNetWithImage(app)
        end

        % Button pushed function: pushbuttonSelArea
        function pushbuttonSelArea_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                global NTlocal selNodes selEdges
            
                selEdges = unique(selEdges);
                figure(app.fig);
                hold on
            
                roi = drawpolyline;
                xp = roi.Position(:,1);
                yp = roi.Position(:,2);
                xc = [xp' roi.Position(1,1)]';
                yc = [yp' roi.Position(1,2)]';
                %plot(xp,yp, 'm')
            
                x = NTlocal.nodepos(:,1);
                y = NTlocal.nodepos(:,2);
                [in on] = inpolygon(x,y, xp,yp);
                delete(roi);
            
                ind = find(in);
                scatter = findobj(gca,'Type','scatter');
                for i=1:length(ind)
                    scatter.CData(ind(i),:) = [0 0 1];
                end
            
                selNodes = [selNodes ind'];
                selNodes = unique(selNodes);

                hold off
            return
        end

        % Button pushed function: pushbuttonSelectEdge
        function pushbuttonSelectEdge_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                global guilock selEdges NTlocal
                if (guilock)
                    disp('Cannot select edges, gui is locked. Finish previous operation.')
                    return
                end
            
                StartAction(app, handles, "Use datatips to select edges. Then hit Enter to complete, Esc to cancel")
                set(gcf,'Pointer','Arrow');
                iSel = selectEdge(app, 'b');
                selEdges = [selEdges iSel];
                selEdges = sort(unique(selEdges));
                EndAction(app, handles)
            
            %     edge = selEdges(1);
            %     Lines = findobj(gca,'Type','line');
            %     lineInd = edge2lineInd(edge, Lines, NTlocal.edgepath);
            
            return
        end

        % Button pushed function: pushbuttonSelectNode
        function pushbuttonSelectNode_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                global guilock
                if (guilock)
                    disp('Cannot select nodes, gui is locked. Finish previous operation.')
                    return
                end
                StartAction(app, handles, "Use datatips to select nodes. Then hit Enter to complete, Esc to cancel")
                set(gcf,'Pointer','Arrow');
                ind = selectNode(app, true, [0 0 1]);
                EndAction(app, handles)
            return
        end

        % Button pushed function: pushbuttonSetParentNode
        function pushbuttonSetParentNode_Callback(app, event)
            % --- Executes on button press in pushbuttonSetParentNode.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbuttonSetParentNode (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global NTobj NTlocal selNodes
            
            
            % set the parent node to the (single) selected node
            if (isempty(selNodes))
                handles.signal.String = 'No nodes selected. Cannot set parent node.';
                return
            elseif (length(selNodes)>1)
                handles.signal.String = 'Multiple nodes selected. Cannot set parent node.';
                return
            end
            
            
            % Update the original network structure.
            locrootnode = selNodes(1); % local index of root node
            
            mapall2act = filterActiveNetwork(app, NTlocal.nodepos, NTlocal.edgenodes,...
                    NTlocal.nodeactive, NTlocal.edgeactive, NTlocal.edgepath,NTlocal.edgewidth,...
                    NTobj);
            
            % new index of root node
            rootnode = mapall2act(locrootnode);
            handles.signal.String = sprintf('Set root node in original network object: %d',rootnode);
            NTobj.rootnode = rootnode;
            selNodes = [];
            
            NTlocal = getNTlocal(app, NTobj);
            dispNetWithImage(app)
            
            % reverse edge directions to make network into a directed tree
            % starting from the root node
            isset = false(NTobj.nedge,1);
            wasreversed = false(NTobj.nedge,1);
            [isset,wasreversed] = directedTreeEdges(NTobj,rootnode,isset,wasreversed);
            handles.signal.String = sprintf('Set root node in original network object: %d. Set directed edges.',rootnode);
            return
        end

        % Button pushed function: pushbuttonUnselectAllEdges
        function pushbuttonUnselectAllEdges_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                global selEdges
            
                figure(app.fig)
                L = findobj(gca,'Type','line');
                for i=1:length(L)
                    L(i).Color = 'g';
                end
                selEdges = [];
            return
        end

        % Button pushed function: pushbuttonUnselectAllNodes
        function pushbuttonUnselectAllNodes_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                global selNodes
            
                figure(app.fig)
                scatter = findobj(gca,'Type','scatter');
                for i=1:length(selNodes)
                    scatter.CData(selNodes(i),:) = [1 0 0];
                end
                selNodes = [];
            return
        end

        % Button pushed function: pushbuttonUnselectEdge
        function pushbuttonUnselectEdge_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                global selEdges
            
                iSel = selectEdge(app, 'g');
            
                if ~isempty(iSel)
                    [val, iRem, iInd] = intersect(selEdges, iSel);
                    selEdges(iRem) = [];
                end
            return
        end

        % Button pushed function: pushbuttonUnselectNode
        function pushbuttonUnselectNode_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                global selNodes
            
                iSel = selectNode(app, false, [1 0 0]);
            
                if ~isempty(iSel)
                     [val, iRem, iInd] = intersect(selNodes, iSel);
                     selNodes(iRem) = [];
                end
            return
        end

        % Button pushed function: pushbuttonWidthCalc
        function pushbuttonWidthCalc_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                global NTobj Hlist
            
                handles.signal.String = 'Calculating widths...';
                for i=1:length(NTobj.edgewidth)
                    NTobj.edgewidth{i} = [];
                end
            
                for i=1:length(Hlist)
                    if isempty(Hlist(i))
                        continue;
                    end
                    if norm(Hlist(i).Position(1,:) - Hlist(i).Position(2,:)) == 0
                        continue;
                    end
            
                    [w, d, iSel] = getWidth(app, Hlist(i).Position);
                    if (iSel == -1) % failed to find intersection
                        disp('Drawn segment does not intersect the nearest edge!')
                        continue;
                    end
            
                    if isempty(NTobj.edgewidth{iSel})
                        NTobj.edgewidth{iSel} = [w d Hlist(i).Position(1,:) Hlist(i).Position(2,:)];
                    else
                        NTobj.edgewidth{iSel} = [NTobj.edgewidth{iSel}' [w d Hlist(i).Position(1,:) Hlist(i).Position(2,:)]']';
                    end
                end
            
                for i=1:length(Hlist)
                    delete(Hlist(i));
                end
                Hlist = [];
                handles.signal.String = '';
                set(handles.pushbuttonWidthCancel,'Enable','off');
                set(handles.pushbuttonWidthCalc,'Enable','off');
                set(handles.pushbuttonWidthMeas,'Enable','on');
            
                disp('Width info for each measurement: width, distance along edge, 1st pt xy, 2nd pt xy')
            return
        end

        % Button pushed function: pushbuttonWidthCancel
        function pushbuttonWidthCancel_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                global Hlist
                for i=1:length(Hlist)
                    delete(Hlist(i));
                end
                Hlist = [];
            
                set(handles.pushbuttonWidthMeas,'Enable','on');
                set(handles.pushbuttonWidthCalc,'Enable','off');
                set(handles.pushbuttonWidthCancel,'Enable','off');
            return
        end

        % Button pushed function: pushbuttonWidthMeas
        function pushbuttonWidthMeas_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                global NTobj guilock Hlist
            
                if (guilock)
                    disp('Cannot measure widths, gui is locked. Finish previous operation.')
                    ind = [];
                    return
                end
                StartAction(app, handles, 'Measure width of desired edges. Press Esc when done.')
                %Calculating edge width...')
            
                Hlist = [];
                % Restore saved interactive lines
                figure(app.fig)
                for i=1:length(NTobj.edgewidth)
                    if isempty(NTobj.edgewidth{i})
                        continue;
                    end
                    for j=1:size(NTobj.edgewidth{i}, 1)
                        L = images.roi.Line(gca);
                        X1 = NTobj.edgewidth{i}(j,3);
                        Y1 = NTobj.edgewidth{i}(j,4);
                        X2 = NTobj.edgewidth{i}(j,5);
                        Y2 = NTobj.edgewidth{i}(j,6);
                        L.Position = [X1 Y1; X2 Y2];
                        L.Color = 'y';
                        Hlist = [Hlist L];
                    end
                end
            
                display('Mark widths of desired edges. Pres Esc when done.')
            
                % Draw new interactive lines
                set(gcf,'CurrentCharacter','a');
                while true
                    figure(app.fig)
                    h = drawline('Color',[1 0.6 0.2]);
                    value = double(get(gcf,'CurrentCharacter'));
                    if value==27
                        break;
                    end
                    if isempty(h)
                        break;
                    end
                    Hlist = [Hlist h];
                end
            
                EndAction(app, handles)
            
                handles.signal.String = 'Adjust width memasurements. Hit Calculate when done';
                set(handles.pushbuttonWidthCancel,'Enable','on');
                set(handles.pushbuttonWidthCalc,'Enable','on');
                % do not allow any more measurements until these are calculated or
                % cancelled
                set(handles.pushbuttonWidthMeas,'Enable','off');
            return
        end

        % Value changed function: sliderBrightness
        function sliderBrightness_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                global imageH B imgObj guilock
                try                    
                    a = app.sliderContrast.Value;
                    b = get(hObject,'Value');
                    imageH.CData = imgObj*a+b
            
                    imageH.CData(imageH.CData>1) = 1;
                    imageH.CData(imageH.CData<0) = 0;
                catch exception
                    guilock = false;
                    disp(getReport(exception))
                end
            return
        end

        % Value changed function: sliderContrast
        function sliderContrast_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                global imageH  imgObj guilock
                try
                    a = get(hObject,'Value');                    
                    b = app.sliderBrightness.Value;
                    imageH.CData = imgObj * a + b;
            
                    imageH.CData(imageH.CData>1) = 1;
                    imageH.CData(imageH.CData<0) = 0;
                catch exception
                    guilock = false;
                    disp(getReport(exception))
                end
            return
        end

        % Button pushed function: pushbuttonSplitEdge
        function pushbuttonSplitEdge_Callback(app, event)
            global NTlocal edgeplotH

            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event);

            StartAction(app,handles,'Click to make a single point on an edge. Press any key when done.')
            figure(app.fig)

            splitpt = drawpoint()
            w = 0;
            while ~w
               w = waitforbuttonpress;
            end

            % 
            [iSel,xy_closest,breakfrac,dMin] = findNearestEdge(app, splitpt.Position);

            if (dMin>5)
                warning('Selected point is too far from an edge (distance %g from edge %d). Try again.',dMin,iSel);
            else
                % break up the edge in the local network structure                
                app.splitEdge(iSel,breakfrac)
                iNew = size(NTlocal.edgenodes,1); % newly created edge index

                % remove prior edge from the plot
                delete(edgeplotH(iSel))

                % plot the 2 newly created edges
                figure(app.fig)
                hold on
                edgeplotH(iSel) = plot(NTlocal.edgepath{iSel}(:,1),NTlocal.edgepath{iSel}(:,2), 'Color','g', 'LineWidth',2);                
                edgeplotH(iSel).addprop('edgeind');
                edgeplotH(iSel).edgeind = iSel;
                edgeplotH(iSel).PickableParts = 'none';
                setPlotDataTips(edgeplotH(iSel),iSel)

                edgeplotH(iNew) = plot(NTlocal.edgepath{iNew}(:,1),NTlocal.edgepath{iNew}(:,2), 'Color','g', 'LineWidth',2);                
                edgeplotH(iNew).addprop('edgeind');
                edgeplotH(iNew).edgeind = iNew;
                edgeplotH(iNew).PickableParts = 'none';
                setPlotDataTips(edgeplotH(iNew),iNew)

                % create a new point for the new node
                scatter = findobj(app.fig,'Type','scatter');                                           
                scatter.SizeData = [scatter.SizeData scatter.SizeData(1)];
                scatter.XData = [scatter.XData NTlocal.nodepos(end,1)];
                scatter.YData = [scatter.YData NTlocal.nodepos(end,2)];
                scatter.CData = [scatter.CData; scatter.CData(1,:)];
            
                scatter.DataTipTemplate.DataTipRows.Value = ...
                    [scatter.DataTipTemplate.DataTipRows.Value  ...
                    (max(scatter.DataTipTemplate.DataTipRows.Value)+1)];

                hold off
                
                % put the nodes on top of the edges
                uistack(scatter,'top')
            end

            % remove selected point roi
            delete(splitpt)

            EndAction(app,handles)
        end

        % Button pushed function: ResetGUIButton
        function ResetGUIButtonPushed(app, event)
            % reset the GUI, turn on all buttons and replot the network            
            global NTobj NTlocal guilock edgeplotH nodeplotH
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event);           

            % remove all rois
            rois = findobj(app.fig, 'Type', 'images.roi');
            delete(rois)

            % replot the network
            NTlocal = getNTlocal(app, NTobj);            
            dispNetWithImage(app)
            
            % turn on all buttons
            ActionsEnable(app,handles)

            % clear selected nodes and edges
            selNodes = [];
            selEdges = [];

            % remove message on top
            app.signal.Text = '';

            % unlock GUI
            guilock = false;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.mainFig = uifigure('Visible', 'off');
            app.mainFig.Name = 'networkEdit v. 1.2';
            app.mainFig.Tag = 'mainFig';
            app.menuFile = uimenu(app.mainFig);
            app.menuFile.Text = 'File';
            app.menuFile.Tag = 'menuFile';
            app.menuLoad = uimenu(app.menuFile);
            app.menuLoad.MenuSelectedFcn = createCallbackFcn(app, @menuLoad_Callback, true);
            app.menuLoad.Text = 'Load';
            app.menuLoad.Tag = 'menuLoad';
            app.menuClear = uimenu(app.menuFile);
            app.menuClear.MenuSelectedFcn = createCallbackFcn(app, @menuClear_Callback, true);
            app.menuClear.Text = 'Clear';
            app.menuClear.Tag = 'menuClear';
            app.menuSave = uimenu(app.menuFile);
            app.menuSave.MenuSelectedFcn = createCallbackFcn(app, @menuSave_Callback, true);
            app.menuSave.Text = 'Save';
            app.menuSave.Tag = 'menuSave';
            app.menuQuit = uimenu(app.menuFile);
            app.menuQuit.MenuSelectedFcn = createCallbackFcn(app, @menuQuit_Callback, true);
            app.menuQuit.Text = 'Quit';
            app.menuQuit.Tag = 'menuQuit';
            app.pushbuttonRemoveSelected = uibutton(app.mainFig, 'push');
            app.pushbuttonRemoveSelected.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonRemoveSelected_Callback, true);
            app.pushbuttonRemoveSelected.Tag = 'pushbuttonRemoveSelected';
            app.pushbuttonRemoveSelected.Text = 'Remove selected';
            app.uipanelEdges = uipanel(app.mainFig);
            app.uipanelEdges.Title = 'Edges';
            app.uipanelEdges.Tag = 'uipanelEdges';
            app.pushbuttonSelectEdge = uibutton(app.uipanelEdges, 'push');
            app.pushbuttonSelectEdge.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonSelectEdge_Callback, true);
            app.pushbuttonSelectEdge.Tag = 'pushbuttonSelectEdge';
            app.pushbuttonSelectEdge.Text = 'Select edge(s)';
            app.pushbuttonUnselectEdge = uibutton(app.uipanelEdges, 'push');
            app.pushbuttonUnselectEdge.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonUnselectEdge_Callback, true);
            app.pushbuttonUnselectEdge.Tag = 'pushbuttonUnselectEdge';
            app.pushbuttonUnselectEdge.Text = 'Unselect';
            app.pushbuttonUnselectAllEdges = uibutton(app.uipanelEdges, 'push');
            app.pushbuttonUnselectAllEdges.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonUnselectAllEdges_Callback, true);
            app.pushbuttonUnselectAllEdges.Tag = 'pushbuttonUnselectAllEdges';
            app.pushbuttonUnselectAllEdges.Text = 'Unselect all';
            app.pushbuttonAddEdge = uibutton(app.uipanelEdges, 'push');
            app.pushbuttonAddEdge.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonAddEdge_Callback, true);
            app.pushbuttonAddEdge.Tag = 'pushbuttonAddEdge';
            app.pushbuttonAddEdge.Text = 'Add';
            app.uibuttongroupWidth = uibuttongroup(app.uipanelEdges);
            app.uibuttongroupWidth.Title = 'Edge width';
            app.uibuttongroupWidth.Tag = 'uibuttongroupWidth';
            app.pushbuttonWidthMeas = uibutton(app.uibuttongroupWidth, 'push');
            app.pushbuttonWidthMeas.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonWidthMeas_Callback, true);
            app.pushbuttonWidthMeas.Tag = 'pushbuttonWidthMeas';
            app.pushbuttonWidthMeas.Text = 'Measure';
            app.pushbuttonWidthCalc = uibutton(app.uibuttongroupWidth, 'push');
            app.pushbuttonWidthCalc.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonWidthCalc_Callback, true);
            app.pushbuttonWidthCalc.Tag = 'pushbuttonWidthCalc';
            app.pushbuttonWidthCalc.Text = 'Calculate';
            app.pushbuttonWidthCancel = uibutton(app.uibuttongroupWidth, 'push');
            app.pushbuttonWidthCancel.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonWidthCancel_Callback, true);
            app.pushbuttonWidthCancel.Tag = 'pushbuttonWidthCancel';
            app.pushbuttonWidthCancel.Enable = 'off';
            app.pushbuttonWidthCancel.Text = 'Cancel';
            app.ShowLoops = uibutton(app.uipanelEdges, 'push');
            app.ShowLoops.ButtonPushedFcn = createCallbackFcn(app, @ShowLoops_Callback, true);
            app.ShowLoops.Tag = 'ShowLoops';
            app.ShowLoops.Text = 'Show Loops';
            app.pushbuttonSplitEdge = uibutton(app.uipanelEdges, 'push');
            app.pushbuttonSplitEdge.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonSplitEdge_Callback, true);
            app.pushbuttonSplitEdge.Tag = 'pushbuttonAddEdge';
            app.pushbuttonSplitEdge.Text = 'Split Edge';
            app.uipanelNodes = uipanel(app.mainFig);
            app.uipanelNodes.Title = 'Nodes';
            app.uipanelNodes.Tag = 'uipanelNodes';
            app.pushbuttonSelectNode = uibutton(app.uipanelNodes, 'push');
            app.pushbuttonSelectNode.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonSelectNode_Callback, true);
            app.pushbuttonSelectNode.Tag = 'pushbuttonSelectNode';
            app.pushbuttonSelectNode.Text = 'Select node(s)';
            app.pushbuttonSelArea = uibutton(app.uipanelNodes, 'push');
            app.pushbuttonSelArea.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonSelArea_Callback, true);
            app.pushbuttonSelArea.Tag = 'pushbuttonSelArea';
            app.pushbuttonSelArea.Text = 'Select in area';
            app.pushbuttonUnselectAllNodes = uibutton(app.uipanelNodes, 'push');
            app.pushbuttonUnselectAllNodes.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonUnselectAllNodes_Callback, true);
            app.pushbuttonUnselectAllNodes.Tag = 'pushbuttonUnselectAllNodes';
            app.pushbuttonUnselectAllNodes.Text = 'Unselect all';
            app.pushbuttonAddNode = uibutton(app.uipanelNodes, 'push');
            app.pushbuttonAddNode.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonAddNode_Callback, true);
            app.pushbuttonAddNode.Tag = 'pushbuttonAddNode';
            app.pushbuttonAddNode.Text = 'Add';
            app.pushbuttonUnselectNode = uibutton(app.uipanelNodes, 'push');
            app.pushbuttonUnselectNode.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonUnselectNode_Callback, true);
            app.pushbuttonUnselectNode.Tag = 'pushbuttonUnselectNode';
            app.pushbuttonUnselectNode.Text = 'Unselect';
            app.pushbuttonColorByDegree = uibutton(app.uipanelNodes, 'push');
            app.pushbuttonColorByDegree.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonColorByDegree_Callback, true);
            app.pushbuttonColorByDegree.Tag = 'pushbuttonColorByDegree';
            app.pushbuttonColorByDegree.Text = 'Color by Degree';
            app.sliderContrast = uislider(app.mainFig);
            app.sliderContrast.Limits = [0.5 1.5];
            app.sliderContrast.MajorTicks = [];
            app.sliderContrast.ValueChangedFcn = createCallbackFcn(app, @sliderContrast_Callback, true);
            app.sliderContrast.MinorTicks = [];
            app.sliderContrast.Tag = 'sliderContrast';
            app.sliderContrast.Value = 1;
            app.text2 = uilabel(app.mainFig);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Image Contrast';
            app.text3 = uilabel(app.mainFig);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Image brightness';
            app.sliderBrightness = uislider(app.mainFig);
            app.sliderBrightness.Limits = [-0.5 0.5];
            app.sliderBrightness.MajorTicks = [];
            app.sliderBrightness.ValueChangedFcn = createCallbackFcn(app, @sliderBrightness_Callback, true);
            app.sliderBrightness.MinorTicks = [];
            app.sliderBrightness.Tag = 'sliderBrightness';
            app.pushbuttonImgReset = uibutton(app.mainFig, 'push');
            app.pushbuttonImgReset.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonImgReset_Callback, true);
            app.pushbuttonImgReset.Tag = 'pushbuttonImgReset';
            app.pushbuttonImgReset.Text = 'Image reset';
            app.pushbuttonMerge = uibutton(app.mainFig, 'push');
            app.pushbuttonMerge.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonMerge_Callback, true);
            app.pushbuttonMerge.Tag = 'pushbuttonMerge';
            app.pushbuttonMerge.Text = 'Merge at selected nodes';
            app.signal = uilabel(app.mainFig);
            app.signal.Tag = 'signal';
            app.signal.Text = '';
            app.pushbuttonFilterActive = uibutton(app.mainFig, 'push');
            app.pushbuttonFilterActive.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonFilterActive_Callback, true);
            app.pushbuttonFilterActive.Tag = 'pushbuttonFilterActive';
            app.pushbuttonFilterActive.Text = 'Update Network (GUI->WS)';
            app.checkboxMergeAll = uicheckbox(app.mainFig);
            app.checkboxMergeAll.Tag = 'checkboxMergeAll';
            app.checkboxMergeAll.Text = 'Merge all';
            app.pushbuttonSetParentNode = uibutton(app.mainFig, 'push');
            app.pushbuttonSetParentNode.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonSetParentNode_Callback, true);
            app.pushbuttonSetParentNode.Tag = 'pushbuttonSetParentNode';
            app.pushbuttonSetParentNode.Text = 'Set Parent Node';
            app.textWillUpdate = uilabel(app.mainFig);
            app.textWillUpdate.Tag = 'textWillUpdate';
            app.textWillUpdate.Text = '(will update network)';
            app.pushbuttonReplotNetwork = uibutton(app.mainFig, 'push');
            app.pushbuttonReplotNetwork.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonReplotNetwork_Callback, true);
            app.pushbuttonReplotNetwork.Tag = 'pushbuttonReplotNetwork';
            app.pushbuttonReplotNetwork.Text = 'Replot Network (WS->GUI)';
            app.ResetGUIButton = uibutton(app.mainFig, 'push');
            app.ResetGUIButton.ButtonPushedFcn = createCallbackFcn(app, @ResetGUIButtonPushed, true);
            app.ResetGUIButton.Text = 'Reset GUI';
            % Show the figure after all components are created
            app.mainFig.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = networkEdit_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.mainFig)

                % Execute the startup function
                runStartupFcn(app, @(app)networkEdit_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.mainFig)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.mainFig)
        end
    end
end