classdef networkGraphEdit < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NetworkGraphEditGUIUIFigure     matlab.ui.Figure
        ReplotNetworkButton             matlab.ui.control.Button
        UndoLastEditButton              matlab.ui.control.Button
        InstructionsEditField           matlab.ui.control.EditField
        InstructionsEditFieldLabel      matlab.ui.control.Label
        ImageControlPanel               matlab.ui.container.Panel
        ImageBrightnessSlider           matlab.ui.control.Slider
        ImageBrightnessSliderLabel      matlab.ui.control.Label
        ImageContrastSlider             matlab.ui.control.Slider
        ImageContrastSliderLabel        matlab.ui.control.Label
        ImageResetButton                matlab.ui.control.Button
        NetworkControlPanel             matlab.ui.container.Panel
        SpatialNetworkAdjustmentsPanel  matlab.ui.container.Panel
        CheckBox                        matlab.ui.control.CheckBox
        EdgepointseparationEditField    matlab.ui.control.NumericEditField
        EdgepointseparationEditFieldLabel  matlab.ui.control.Label
        AdjustNetworkButton             matlab.ui.control.StateButton
        DrawandModifyPanel              matlab.ui.container.Panel
        SelectNodesinRegionsButton      matlab.ui.control.Button
        RegionButton                    matlab.ui.control.Button
        AddDrawnEdgesButton             matlab.ui.control.Button
        RemoveDrawnElementsButton       matlab.ui.control.Button
        LineButton                      matlab.ui.control.Button
        BreakEdgesatPointsButton        matlab.ui.control.Button
        AddDrawnNodesButton             matlab.ui.control.Button
        PointsButton                    matlab.ui.control.Button
        SelectandModifyButtonGroup      matlab.ui.container.ButtonGroup
        MergeEdgesatNodesButton         matlab.ui.control.Button
        RemoveEdgesButton               matlab.ui.control.Button
        RemoveNodesButton               matlab.ui.control.Button
        NoneButton                      matlab.ui.control.ToggleButton
        EdgesButton                     matlab.ui.control.ToggleButton
        NodesButton                     matlab.ui.control.ToggleButton
    end

    
    properties (Access = public)        
        NT % networkGraph object
        NTprev % previous networkGraph object, saved for a single undo step
        img % background image
        imgH % handle to image graphics object
        graphplotH % handle to network graph graphics object
        edgeplotH % handles to edge path graphics objects
        spNT % adjustable network object
        plotfig % figure handle to use for plotting
        plotax % axes to use for plotting
        plotopt % plotting options
        dcm % data cursor manager  
        currentlyDrawing = false; % keep track if app is currently occupied with interactive drawing
    end
    
    methods (Access = private)
        
        function results = displayNetworkImg(app)
            % create a new figure to interact with
            if (isempty(app.plotfig))
                app.plotfig = figure;
                app.plotax = gca;
                set(app.plotax,'Position',[0 0 1 1])
                dcm = datacursormode(app.plotfig);
            end
            
            % display current network object overlaid on image
            if (~isempty(app.img))
                app.imgH = imshow(app.img,'Parent',app.plotax);
            end
            
            if (~isempty(app.NT))     
                app.replotNetwork()
            end
        end

        
        function setImageBrightness(app)
            contrast = app.ImageContrastSlider.Value;
            brightness = app.ImageBrightnessSlider.Value;
            
            app.imgH.CData = (app.img + brightness)* contrast;
            
            app.imgH.CData(app.imgH.CData>1) = 1;
            app.imgH.CData(app.imgH.CData<0) = 0;
        end
        
         function toggleEdgePickability(app,pickable)
            % toggle between edge paths being pickable or not
            for ec = 1:length(app.edgeplotH)
               app.edgeplotH(ec).PickableParts = pickable;
            end
        end
        
        function results = setSelectionMode(app)
            % set selection mode basedon current value of the selection
            % mode button
            selectedButton = app.SelectandModifyButtonGroup.SelectedObject;
            
            if (isempty(app.dcm))
                app.dcm = datacursormode(app.plotfig);
            end
                        
            % remove all existing cursors
            app.dcm.removeAllDataCursors();                       
            
            switch(selectedButton.Text)
                case('None')
                    % neither edges nor nodes are selectable                   
                    app.graphplotH.PickableParts = 'none';   
                    app.toggleEdgePickability('none');
                    plotedit(app.plotfig,'off')
                case('Nodes')
                    app.graphplotH.PickableParts = 'all';  
                    app.toggleEdgePickability('none');
                    plotedit(app.plotfig,'off')
                case('Edges')
                    app.graphplotH.PickableParts = 'none';  
                    app.toggleEdgePickability('all');
                    % use plot editing feature to select edges
                    plotedit(app.plotfig,'on')
                    app.dcm.Enable = 'off';
                    
                    % remove existing selected edges
                    seledges = app.findSelectedEdges();
                    for ec = seledges
                        app.edgeplotH(ec).Selected = 'off';
                    end
            end
        end
        
        function seledges = findSelectedEdges(app)
            % get currently selected edges
            seledges = find(cellfun(@(x) strcmp(x,'on'),{app.edgeplotH.Selected}));
        end

        
        function results = toggleNetworkEditButtons(app,enable,spatial)
            % enable or disable all the buttons associated with changing
            % network topology
            % if spatial is true, include buttons for spatial network
            % adjustments
            
            buttons = get(app.DrawandModifyPanel,'Children');
            set(buttons,'Enable',enable)
            buttons = get(app.SelectandModifyButtonGroup,'Children');
            set(buttons,'Enable',enable)
            app.UndoLastEditButton.Enable = enable;
            app.ReplotNetworkButton.Enable = enable;
            
            if (spatial)
                    buttons = get(app.SpatialNetworkAdjustmentsPanel,'Children');
                    set(buttons,'Enable',enable)                
            end
        end
        
    end
    
    methods (Access = public)
        
        function replotNetwork(app)
             % replot just the network, without changing the image
            
%              if (app.AdjustNetworkButton.Value)
%                 % replot adjustable network
%                  
%                  app.spNT.delete()
%                     app.spNT = adjustableNetwork(app.NT)
%                  
%                  return
%              end
             
            % erase prior networkplot
            if (~isempty(app.graphplotH))
                if (isvalid(app.graphplotH))
                    delete(app.graphplotH);
                end
            end
            if (~isempty(app.edgeplotH))
                if(any(isvalid(app.edgeplotH)))
                    delete(app.edgeplotH)
                end
            end            
            
            % plot network anew
            hold(app.plotax,'all')
            app.plotopt.Parent = app.plotax;
            [app.graphplotH,app.edgeplotH] = app.NT.plotNetwork(app.plotopt);
            hold(app.plotax,'off')
            app.setSelectionMode()
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            disp('Setting up network editor app')
            
            %% set defaults
            app.NT = [];
            app.img = [];
            % default plotting options
            app.plotopt = struct('nodecolor','r');             
            app.plotopt.edgeplotopt = {'LineWidth',2,'Color','g'};
            
            % optional arguments
            for index = 1:2:length(varargin)
                switch(lower(varargin{index}))
                    case 'nt'
                        app.NT = varargin{index+1};
                    case 'img'
                        app.img = varargin{index+1};
                    case('plotopt')
                        app.plotopt = varargin{index+1};
                end
          
            end
            
            % normalize img            
            app.img = app.img - min(app.img(:));
            app.img = double(app.img);
            app.img = app.img/max(app.img(:));
            
            app.displayNetworkImg()
        end

        % Button pushed function: ImageResetButton
        function ImageResetButtonPushed(app, event)
            app.ImageContrastSlider.Value = 1;
            app.ImageBrightnessSlider.Value = 0;
            app.setImageBrightness();
        end

        % Selection changed function: SelectandModifyButtonGroup
        function SelectandModifyButtonGroupSelectionChanged(app, event)
            app.setSelectionMode()
        end

        % Value changed function: ImageContrastSlider
        function ImageContrastSliderValueChanged(app, event)
            app.setImageBrightness();            
        end

        % Value changed function: ImageBrightnessSlider
        function ImageBrightnessSliderValueChanged(app, event)
            app.setImageBrightness();
        end

        % Button pushed function: RemoveNodesButton
        function RemoveNodesButtonPushed(app, event)
            % remove currently selected nodes
            
            % find which nodes are currently selected (via datatips)
            datatips = findobj(app.plotax,'Type','datatip');
            if (isempty(datatips))
                uialert(app.NetworkGraphEditGUIUIFigure,'No nodes are selected.', 'Remove Nodes Error')
            else
                app.NTprev = copy(app.NT);
                
                selnodes = [datatips.DataIndex];
                
                dokeep = true(1,app.NT.graph.numnodes);
                dokeep(selnodes) = false;
                app.NT.keepNodes(find(dokeep));
                
                 % replot the network
                app.replotNetwork()
            end
            
           
        end

        % Button pushed function: UndoLastEditButton
        function UndoLastEditButtonPushed(app, event)
            app.NT = copy(app.NTprev);
            app.replotNetwork()
        end

        % Button pushed function: RemoveEdgesButton
        function RemoveEdgesButtonPushed(app, event)
                 
            % find which edges are currently selected
            seledges = app.findSelectedEdges();
            if (isempty(seledges))
                uialert(app.NetworkGraphEditGUIUIFigure,'No edges are selected.', 'Remove Edges Error')
            else
                app.NTprev = copy(app.NT);
                app.NT.rmEdges(seledges);
                
                 % replot the network
                app.replotNetwork()
            end
        end

        % Button pushed function: AddDrawnNodesButton
        function AddDrawnNodesButtonPushed(app, event)
            % add new nodes at positions drawn (do not connect to any
            % others)
            
            if (app.currentlyDrawing)
                uialert(app.NetworkGraphEditGUIUIFigure,'App is currently occupied with user drawing. Select Figure and hit Esc if done.', 'Error: finish drawing!')
                return
            end
            
            % get current roi points    
            roipts = findobj(app.plotfig,'Type','images.roi.point');
            
            if (isempty(roipts))
                uialert(app.NetworkGraphEditGUIUIFigure,'No points found. Use Draw Points.', 'Add Drawn Nodes Error')
                return
            end
          
            nodepos = vertcat(roipts.Position);            
            app.NT.addNodes(nodepos)
           
            app.replotNetwork();             
             app.InstructionsEditField.Value = '';           
            % delete points
            delete(roipts)
        end

        % Button pushed function: PointsButton
        function PointsButtonPushed(app, event)
            % add scattered editable points            
            
            app.InstructionsEditField.Value = 'Click to select points on figure. Hit Esc when done.';                      
            
            % turn off other selection features
            if (~app.AdjustNetworkButton.Value)
                app.NoneButton.Value = 1;
                app.setSelectionMode();
            end
            
            app.currentlyDrawing = true;
            app.toggleNetworkEditButtons('off',true)
            
            % select points            
            pointhandles = gobjects(); 
            while app.currentlyDrawing
                a = drawpoint();     
                if ~isvalid(a) || isempty(a.Position)        
                    % End the loop        
                    app.currentlyDrawing = false; 
                else        
                    % store point object handle
                    pointhandles(end+1) = a;                     
                end
            end
            
            app.toggleNetworkEditButtons('on',true)
            app.InstructionsEditField.Value = 'Adjust points if needed. Add Nodes to insert nodes into edges.';            
        end

        % Button pushed function: RemoveDrawnElementsButton
        function RemoveDrawnElementsButtonPushed(app, event)
            %remove any rois currently in the figure
            
            roiobj = findobj(app.plotfig,'Type','images.roi');
            delete(roiobj)
            
            app.InstructionsEditField.Value = ''; 
        end

        % Value changed function: AdjustNetworkButton
        function AdjustNetworkButtonValueChanged(app, event)
             
            prevpointer = get(app.plotfig,'Pointer');
            set(app.plotfig,'Pointer','watch')           
            
            % redistribute points along edges?
            redistrib = app.CheckBox.Value;
            if (redistrib)
                app.InstructionsEditField.Value = 'Redistributing edge path points';
                app.NT.redistributeEdgePaths(app.EdgepointseparationEditField.Value)                
            end
            
            value = app.AdjustNetworkButton.Value;
            
            
            if (value==1) % set up adjustable network                                
                                
                app.InstructionsEditField.Value = 'Turning on adjustable network. Be patient, this can take a few sec.';
                pause(0.05)
                
                app.NoneButton.Value = 1;
                app.setSelectionMode()
                delete(app.graphplotH)
                delete(app.edgeplotH)
                app.spNT = adjustableNetwork(app.NT)
                
                % turn off network editing features
                app.toggleNetworkEditButtons('off',false);
                app.InstructionsEditField.Value = 'Drag nodes and edge points to adjust network, without changing topology.';
                set(app.plotfig,'Pointer',prevpointer)               
            else % stop adjusting network, replot                
                
                app.InstructionsEditField.Value = 'Turning off adjustable network. Be patient, this can take a few sec.';
                pause(0.05)
                
                app.spNT.delete() % clear all the adjustable handles
                % reinterpolateEdges                
                app.replotNetwork()
                
                % turn on network editing features
                 app.toggleNetworkEditButtons('on',false);
                app.InstructionsEditField.Value = 'Select and modify or draw new elements.';
                set(app.plotfig,'Pointer',prevpointer);              
            end
             app.InstructionsEditField.Value = '';          
        end

        % Button pushed function: MergeEdgesatNodesButton
        function MergeEdgesatNodesButtonPushed(app, event)
            % find which nodes are currently selected (via datatips)
            datatips = findobj(app.plotax,'Type','datatip');
            if (isempty(datatips))
                uialert(app.NetworkGraphEditGUIUIFigure,'No nodes are selected.', 'Merge Edges Error')
            else
                app.NTprev = copy(app.NT);
                
                for dc = 1:length(datatips)
                    parts = split(datatips(1).Content, ' ');
                    names{dc} = parts{end};                    
                end
                %selnodes = [datatips.DataIndex];
                selnodes = findnode(app.NT.graph,names);
                degrees = outdegree(app.NT.graph,selnodes)+indegree(app.NT.graph,selnodes);
                for dc = 1:length(datatips)
                    if (degrees(dc)==2)
                        app.NT.mergeEdgesAtNode(names{dc});                    
                    end
                end
                    
                 % replot the network
                app.replotNetwork()
            end
        end

        % Button pushed function: ReplotNetworkButton
        function ReplotNetworkButtonPushed(app, event)
            % replot the network
            app.replotNetwork()
        end

        % Button pushed function: BreakEdgesatPointsButton
        function BreakEdgesatPointsButtonPushed(app, event)
             % get current roi points    
            roipts = findobj(app.plotfig,'Type','images.roi.point');
            
            if (isempty(roipts))
                uialert(app.NetworkGraphEditGUIUIFigure,'No points found. Use Draw Points.', 'Break Edges Error')
                return
            end
            
             app.NTprev = copy(app.NT);
             
             % find position along edge
             nodepos = vertcat(roipts.Position);
             for nc = 1:size(nodepos,1)
                [xy,edgeind,edgepos,distance] = app.NT.getNearestEdgePoint(nodepos(nc,:));
                app.NT.breakEdge(edgeind,edgepos);
             end             
             
             app.replotNetwork();
             
             app.InstructionsEditField.Value = '';
%             delete(pt);

            % delete points
            delete(roipts)
        end

        % Button pushed function: LineButton
        function LineButtonPushed(app, event)
            % add editable line    
            
            app.InstructionsEditField.Value = 'Click to draw broken line. Right-click when done.';                      
            
            % turn off other selection features
            if (~app.AdjustNetworkButton.Value)
                app.NoneButton.Value = 1;
                app.setSelectionMode();
            end
            
            app.currentlyDrawing = true;
            % turn off all buttons until user finishes selecting line
            app.toggleNetworkEditButtons('off',true)
            
            % select points                       
            lineH = drawpolyline();     
             
            app.InstructionsEditField.Value = 'Adjust poly-line if needed. Add Edges to insert new edges. Endpoints will snap to nodes.';            
            app.currentlyDrawing = false;
            app.toggleNetworkEditButtons('on',true)
        end

        % Button pushed function: AddDrawnEdgesButton
        function AddDrawnEdgesButtonPushed(app, event)
            if (app.currentlyDrawing)
                uialert(app.NetworkGraphEditGUIUIFigure,'App is currently occupied with user drawing. Select Figure and hit Esc if done.', 'Error: finish drawing!')
                return
            end
            
            % get current roi points    
            roilines = findobj(app.plotfig,'Type','images.roi.polyline');
            
            if (isempty(roilines))
                uialert(app.NetworkGraphEditGUIUIFigure,'No poly-lines found. Use Draw Line.', 'Add Drawn Edges Error')
                return
            end
            
            nodepos = app.NT.graph.Nodes.pos;
          
            newedge = [];
            newpath = {};
            
            for rc = 1:length(roilines)
                % find nearest graph nodes to endpoints
                newpath{rc} = roilines(rc).Position;
                np = size(newpath{rc},1);
                pos = newpath{rc}([1,np],:);
                [nodeIDs, nodepos] = app.NT.nearestNode(pos);
                
                newedge(rc,:) = nodeIDs';
                newpath{rc}(1,:) = nodepos(1,:);
                newpath{rc}(end,:) = nodepos(2,:);                          
            end
            
            app.NT.addEdges(newedge(:,1),newedge(:,2),newpath)
            app.replotNetwork();             
             app.InstructionsEditField.Value = '';           
            % delete points
            delete(roilines)
        end

        % Button pushed function: RegionButton
        function RegionButtonPushed(app, event)
            % add editable polygonal region
            
            app.InstructionsEditField.Value = 'Click to select polygonal region.';                      
            
            % turn off other selection features
            if (~app.AdjustNetworkButton.Value)
                app.NoneButton.Value = 1;
                app.setSelectionMode();
            end
            
            app.currentlyDrawing = true;
            % turn off all buttons until user finishes selecting line
            app.toggleNetworkEditButtons('off',true)
            
            % select points                       
            polyH = drawpolygon();     
             
            app.InstructionsEditField.Value = 'Adjust polygon if needed.';            
            app.currentlyDrawing = false;
            app.toggleNetworkEditButtons('on',true)
        end

        % Button pushed function: SelectNodesinRegionsButton
        function SelectNodesinRegionsButtonPushed(app, event)
            
            % pick out all nodes within the selected polygons
             app.InstructionsEditField.Value = 'Selecting all nodes within polygons.';    
            
            % get all polygons on figure   
            roipoly= findobj(app.plotfig,'Type','images.roi.polygon');
            
            if (isempty(roipoly))
                uialert(app.NetworkGraphEditGUIUIFigure,'No polygons found. Use Draw Region.', 'Select Nodesin Regions Error')
                return
            end
            
            nodepos = app.NT.graph.Nodes.pos;
            
            % pick out all nodes in this polygon
            inreg = false(size(nodepos,1),1);
            for rc = 1:length(roipoly)           
                regpos = roipoly(rc).Position;
                inreg = inreg | inpolygon(nodepos(:,1),nodepos(:,2),regpos(:,1),regpos(:,2));
            end
            
            pickpt = find(inreg);   
                        
            % remove polygons
            delete(roipoly)
            
            graphplot = findobj(app.plotfig,'Type','GraphPlot');
            for pc = 1:length(pickpt)
                datatip(graphplot,'DataIndex',pickpt(pc));
            end
            
            % set selection mode to Nodes
            nodebut = findobj(app.SelectandModifyButtonGroup,'Text','Nodes')
            nodebut.Value=true;
%             app.setSelectionMode()
            
            app.InstructionsEditField.Value = '';   
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.NetworkGraphEditGUIUIFigure = uifigure('Visible', 'off');
            app.NetworkGraphEditGUIUIFigure.Name = 'Network Graph Edit GUI';
            app.NetworkControlPanel = uipanel(app.NetworkGraphEditGUIUIFigure);
            app.NetworkControlPanel.Title = 'Network Control';
            app.SelectandModifyButtonGroup = uibuttongroup(app.NetworkControlPanel);
            app.SelectandModifyButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SelectandModifyButtonGroupSelectionChanged, true);
            app.SelectandModifyButtonGroup.Title = 'Select and Modify';
            app.NodesButton = uitogglebutton(app.SelectandModifyButtonGroup);
            app.NodesButton.Text = 'Nodes';
            app.EdgesButton = uitogglebutton(app.SelectandModifyButtonGroup);
            app.EdgesButton.Text = 'Edges';
            app.NoneButton = uitogglebutton(app.SelectandModifyButtonGroup);
            app.NoneButton.Text = 'None';
            app.NoneButton.Value = true;
            app.RemoveNodesButton = uibutton(app.SelectandModifyButtonGroup, 'push');
            app.RemoveNodesButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveNodesButtonPushed, true);
            app.RemoveNodesButton.Text = 'Remove Nodes';
            app.RemoveEdgesButton = uibutton(app.SelectandModifyButtonGroup, 'push');
            app.RemoveEdgesButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveEdgesButtonPushed, true);
            app.RemoveEdgesButton.Text = 'Remove Edges';
            app.MergeEdgesatNodesButton = uibutton(app.SelectandModifyButtonGroup, 'push');
            app.MergeEdgesatNodesButton.ButtonPushedFcn = createCallbackFcn(app, @MergeEdgesatNodesButtonPushed, true);
            app.MergeEdgesatNodesButton.Text = 'Merge Edges at Nodes';
            app.DrawandModifyPanel = uipanel(app.NetworkControlPanel);
            app.DrawandModifyPanel.Title = 'Draw and Modify';
            app.PointsButton = uibutton(app.DrawandModifyPanel, 'push');
            app.PointsButton.ButtonPushedFcn = createCallbackFcn(app, @PointsButtonPushed, true);
            app.PointsButton.Text = 'Points';
            app.AddDrawnNodesButton = uibutton(app.DrawandModifyPanel, 'push');
            app.AddDrawnNodesButton.ButtonPushedFcn = createCallbackFcn(app, @AddDrawnNodesButtonPushed, true);
            app.AddDrawnNodesButton.Text = 'Add Drawn Nodes';
            app.BreakEdgesatPointsButton = uibutton(app.DrawandModifyPanel, 'push');
            app.BreakEdgesatPointsButton.ButtonPushedFcn = createCallbackFcn(app, @BreakEdgesatPointsButtonPushed, true);
            app.BreakEdgesatPointsButton.Text = 'Break Edges at Points';
            app.LineButton = uibutton(app.DrawandModifyPanel, 'push');
            app.LineButton.ButtonPushedFcn = createCallbackFcn(app, @LineButtonPushed, true);
            app.LineButton.Text = 'Line';
            app.RemoveDrawnElementsButton = uibutton(app.DrawandModifyPanel, 'push');
            app.RemoveDrawnElementsButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveDrawnElementsButtonPushed, true);
            app.RemoveDrawnElementsButton.Text = 'Remove Drawn Elements';
            app.AddDrawnEdgesButton = uibutton(app.DrawandModifyPanel, 'push');
            app.AddDrawnEdgesButton.ButtonPushedFcn = createCallbackFcn(app, @AddDrawnEdgesButtonPushed, true);
            app.AddDrawnEdgesButton.Text = 'Add Drawn Edges';
            app.RegionButton = uibutton(app.DrawandModifyPanel, 'push');
            app.RegionButton.ButtonPushedFcn = createCallbackFcn(app, @RegionButtonPushed, true);
            app.RegionButton.Text = 'Region';
            app.SelectNodesinRegionsButton = uibutton(app.DrawandModifyPanel, 'push');
            app.SelectNodesinRegionsButton.ButtonPushedFcn = createCallbackFcn(app, @SelectNodesinRegionsButtonPushed, true);
            app.SelectNodesinRegionsButton.Text = 'Select Nodes in Region(s)';
            app.SpatialNetworkAdjustmentsPanel = uipanel(app.NetworkControlPanel);
            app.SpatialNetworkAdjustmentsPanel.Title = 'Spatial Network Adjustments';
            app.AdjustNetworkButton = uibutton(app.SpatialNetworkAdjustmentsPanel, 'state');
            app.AdjustNetworkButton.ValueChangedFcn = createCallbackFcn(app, @AdjustNetworkButtonValueChanged, true);
            app.AdjustNetworkButton.Text = {'Adjust Network'; ''};
            app.EdgepointseparationEditFieldLabel = uilabel(app.SpatialNetworkAdjustmentsPanel);
            app.EdgepointseparationEditFieldLabel.Text = 'Edge point separation:';
            app.EdgepointseparationEditField = uieditfield(app.SpatialNetworkAdjustmentsPanel, 'numeric');
            app.EdgepointseparationEditField.Value = 5;
            app.CheckBox = uicheckbox(app.SpatialNetworkAdjustmentsPanel);
            app.CheckBox.Text = '';
            app.CheckBox.Value = true;
            app.ImageControlPanel = uipanel(app.NetworkGraphEditGUIUIFigure);
            app.ImageControlPanel.Title = 'Image Control';
            app.ImageResetButton = uibutton(app.ImageControlPanel, 'push');
            app.ImageResetButton.ButtonPushedFcn = createCallbackFcn(app, @ImageResetButtonPushed, true);
            app.ImageResetButton.Text = 'Image Reset';
            app.ImageContrastSliderLabel = uilabel(app.ImageControlPanel);
            app.ImageContrastSliderLabel.Text = 'Image Contrast';
            app.ImageContrastSlider = uislider(app.ImageControlPanel);
            app.ImageContrastSlider.Limits = [0 4];
            app.ImageContrastSlider.MajorTicks = [];
            app.ImageContrastSlider.ValueChangedFcn = createCallbackFcn(app, @ImageContrastSliderValueChanged, true);
            app.ImageContrastSlider.MinorTicks = [];
            app.ImageContrastSlider.Value = 1;
            app.ImageBrightnessSliderLabel = uilabel(app.ImageControlPanel);
            app.ImageBrightnessSliderLabel.Text = 'Image Brightness';
            app.ImageBrightnessSlider = uislider(app.ImageControlPanel);
            app.ImageBrightnessSlider.Limits = [-0.6 0.6];
            app.ImageBrightnessSlider.MajorTicks = [];
            app.ImageBrightnessSlider.ValueChangedFcn = createCallbackFcn(app, @ImageBrightnessSliderValueChanged, true);
            app.ImageBrightnessSlider.MinorTicks = [];
            app.InstructionsEditFieldLabel = uilabel(app.NetworkGraphEditGUIUIFigure);
            app.InstructionsEditFieldLabel.Text = 'Instructions:';
            app.InstructionsEditField = uieditfield(app.NetworkGraphEditGUIUIFigure, 'text');
            app.InstructionsEditField.Editable = 'off';
            app.UndoLastEditButton = uibutton(app.NetworkGraphEditGUIUIFigure, 'push');
            app.UndoLastEditButton.ButtonPushedFcn = createCallbackFcn(app, @UndoLastEditButtonPushed, true);
            app.UndoLastEditButton.Text = 'Undo Last Edit';
            app.ReplotNetworkButton = uibutton(app.NetworkGraphEditGUIUIFigure, 'push');
            app.ReplotNetworkButton.ButtonPushedFcn = createCallbackFcn(app, @ReplotNetworkButtonPushed, true);
            app.ReplotNetworkButton.Text = 'Replot Network';
            % Show the figure after all components are created
            app.NetworkGraphEditGUIUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = networkGraphEdit(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NetworkGraphEditGUIUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NetworkGraphEditGUIUIFigure)
        end
    end
end